classdef EEG_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        SignaloptionsandartefactsPanel  matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        EyeblinkCheckBox                matlab.ui.control.CheckBox
        EMGeffectsCheckBox              matlab.ui.control.CheckBox
        EOGeffectsCheckBox              matlab.ui.control.CheckBox
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        MovementStartingTimeLabel       matlab.ui.control.Label
        MovementStartingTimeEditField   matlab.ui.control.NumericEditField
        GeneratedataButton              matlab.ui.control.Button
        PowerlinesCheckBox              matlab.ui.control.CheckBox
        MovementDropDownLabel           matlab.ui.control.Label
        MovementDropDown                matlab.ui.control.DropDown
        FilePanel                       matlab.ui.container.Panel
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        LoaddataButton                  matlab.ui.control.Button
        PreprocessingTab                matlab.ui.container.Tab
        SpatialFilteringPanel           matlab.ui.container.Panel
        ApplyspatialfilteringButton     matlab.ui.control.Button
        FilteringMethodDropDownLabel    matlab.ui.control.Label
        FilteringMethodDropDown         matlab.ui.control.DropDown
        TemporalFilteringPanel          matlab.ui.container.Panel
        BandPassFilterParametersPanel   matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        WindowTypeLabel                 matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        NotchFilterPanel                matlab.ui.container.Panel
        FreqEditFieldLabel              matlab.ui.control.Label
        FreqEditField                   matlab.ui.control.NumericEditField
        HzLabel_6                       matlab.ui.control.Label
        ApplytemporalfilteringButton    matlab.ui.control.Button
        FiltersPanel                    matlab.ui.container.Panel
        NotchFilterCheckBox             matlab.ui.control.CheckBox
        BPFilterCheckBox                matlab.ui.control.CheckBox
        FrequencybandsButtonGroup       matlab.ui.container.ButtonGroup
        ClassicalfrequencybandsButton   matlab.ui.control.RadioButton
        PersonnalisedfrequencybandsButton  matlab.ui.control.RadioButton
        ClassicalfrequencybandsPanel    matlab.ui.container.Panel
        muRythmCheckBox                 matlab.ui.control.CheckBox
        deltaRythmCheckBox              matlab.ui.control.CheckBox
        thetaRythmCheckBox              matlab.ui.control.CheckBox
        alphaRythmCheckBox              matlab.ui.control.CheckBox
        betaRythmCheckBox               matlab.ui.control.CheckBox
        PersonnalisedfrequencybandsPanel  matlab.ui.container.Panel
        NbofbandsEditFieldLabel         matlab.ui.control.Label
        NbofbandsEditField              matlab.ui.control.NumericEditField
        LFreqEditField_2Label           matlab.ui.control.Label
        LFreqEditField_perso            matlab.ui.control.NumericEditField
        HFreqEditField_2Label           matlab.ui.control.Label
        HFreqEditField_perso            matlab.ui.control.NumericEditField
        ArtifactRejectionPanel          matlab.ui.container.Panel
        ApplyrejectionButton            matlab.ui.control.Button
        ExtractionTab                   matlab.ui.container.Tab
        ExtractionMethodButtonGroup     matlab.ui.container.ButtonGroup
        PowerbandsButton                matlab.ui.control.RadioButton
        WaveletsButton                  matlab.ui.control.RadioButton
        ARModelButton                   matlab.ui.control.RadioButton
        PeakDetectionButton             matlab.ui.control.RadioButton
        VarianceButton                  matlab.ui.control.RadioButton
        TemplateMatchingButton          matlab.ui.control.RadioButton
        TimeIntegrationButton           matlab.ui.control.RadioButton
        BuildFeatureVectorButton        matlab.ui.control.Button
        ClassificiationTab              matlab.ui.container.Tab
        ExtractionMethodButtonGroup_2   matlab.ui.container.ButtonGroup
        LDAButton                       matlab.ui.control.RadioButton
        SVMButton                       matlab.ui.control.RadioButton
        ARModelButton_2                 matlab.ui.control.RadioButton
        AnalysisTab                     matlab.ui.container.Tab
        ResultsTab                      matlab.ui.container.Tab
    end

    methods (Access = private)

        % Button pushed function: BuildFeatureVectorButton
        function FeatureExtraction(app, event)
            %% Which Extraction Method is going to be used?
            PowerBands=int8(app.PowerbandsButton.Value);
            Wavelets=int8(app.WaveletsButton.Value);
            ARModel=int8(app.ARModelButton.Value);
            PeakDetection=int8(app.PeakDetectionButton.Value);
            Variance=int8(app.VarianceButton.Value);
            TemplateMatching=int8(app.TemplateMatchingButton.Value);
            TimeIntegration=int8(app.TimeIntegrationButton.Value);  
            
            %% Which type of filtering has been used
            NbOfBands=app.NbofbandsEditField.Value;
            ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
            muRythm=int8(app.muRythmCheckBox.Value);
            alphaRythm=int8(app.muRythmCheckBox.Value);
            betaRythm=int8(app.betaRythmCheckBox.Value);
            deltaRythm=int8(app.deltaRythmCheckBox.Value);
            thetaRythm=int8(app.thetaRythmCheckBox.Value);
            
            
            if (PeakDetection==1)
                method='PeakDetection';
            elseif (Wavelets==1)
                method='Wavelets';
            elseif (ARModel==1)
                method='ARModel';    
            elseif (PowerBands==1)
                method='ARModel';    
            elseif (Variance==1)
                method='Variance';    
            elseif (TemplateMatching==1)  
                method='TemplateMatching'; 
            elseif (TimeIntegration==1)  
                method='TimeIntegration'; 
            end
            
            %% Load the data
            if (ClassicalFrequencyBands==1)         
                if (muRythm==1)
                    myMuSpatialFilteredData=evalin('base','myMuSpatialFilteredData');  
                    [TempFeatureVector]=myFeatureExtraction(myMuSpatialFilteredData,method);
                end
                if (alphaRythm==1)
                    myAlphaSpatialFilteredData=evalin('base','myAlphaSpatialFilteredData');
                    [TempFeatureVector]=myFeatureExtraction(myAlphaSpatialFilteredData,method);
                end
                if (betaRythm==1)
                    myBetaSpatialFilteredData=evalin('base','myBetaSpatialFilteredData');
                    [TempFeatureVector]=myFeatureExtraction(myBetaSpatialFilteredData,method);
                end
                if (deltaRythm==1)
                    myDeltaSpatialFilteredData=evalin('base','myDeltaSpatialFilteredData');
                    [TempFeatureVector]=myFeatureExtraction(myDeltaSpatialFilteredData,method);
                end
                if (thetaRythm==1)
                    myThetaSpatialFilteredData=evalin('base','myThetaSpatialFilteredData');
                    [TempFeatureVector]=myFeatureExtraction(myThetaSpatialFilteredData,method);
                end
            else
                myFreqBandsSpatialFilteredData=evalin('base','myDeltaSpatialFilteredData');
                while n<NbOfBands+1
                    n=n+1;
                end
            end     
           
           FeatureVector=TempFeatureVector;
           assignin('base', 'FeatureVector', FeatureVector);
        end

        % Button pushed function: GeneratedataButton
        function Generate(app, event)
            close all;
            
            %% To Generate the signals
            Fs=app.FsEditField.Value;
            
            assignin('base', 'Fs', Fs)
            SNR=app.SNREditField.Value;
            assignin('base', 'SNR', SNR)
            time_movement=app.MovementStartingTimeEditField.Value;
            t_end=app.DurationEditField.Value;
            time=linspace(0,t_end,Fs*t_end+1);
            assignin('base', 'time',time)
            assignin('base', 'duration', t_end)
            eye_blink_check=int8(app.EyeblinkCheckBox.Value);
            power_line_check=int8(app.PowerlinesCheckBox.Value);
            myMove=app.MovementDropDown.Value;
            
            if strcmp(myMove,'Right hand')
                movement=1;
            elseif strcmp(myMove,'Left hand')
                movement=2;
            else
                movement=3;
            end
            
            [C3, C4, Cz]=eeg_generator(Fs,t_end,SNR,movement, time_movement,eye_blink_check,power_line_check);
            
            %% Signals are created and I want to store them
            rawData=[C3;C4;Cz];
            assignin('base', 'rawData',rawData)
            
            %% Save the signals and parameters
            save('rawData.mat','C3','C4','Cz') 
            parameters=[];
            parameters.duration=duration;
            parameters.fsample=Fs;
            parameters.time=time;
            parameters.timestart=time_movement;
            parameters.movement=movement;
            parameters.eye_blink_check=eye_blink_check;
            parameters.power_line_check=power_line_check;
            save('parameters.mat', 'parameters')
            
            %% Create the FieldTrip Structure
            [myFtData]=createFtStruct();
            assignin('base', 'myFtData',myFtData);
            
            %% Frequency analysis
            [mySpectralData]=FreqTransform(myFtData, 'raw data')
        end

        % Button pushed function: LoaddataButton
        function Load(app, event)
            filename=app.FilenameEditField.Value
            file=load(filename)
            assignin('base', 'myData', file)
            
        end

        % Button pushed function: ApplyrejectionButton
        function RejectArtifact(app, event)
            close all;
            
            Type=app.FilteringMethodDropDown.Value;
            
            %% For the classical frequency bands
            ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
            muRythm=int8(app.muRythmCheckBox.Value);
            alphaRythm=int8(app.muRythmCheckBox.Value);
            betaRythm=int8(app.betaRythmCheckBox.Value);
            deltaRythm=int8(app.deltaRythmCheckBox.Value);
            thetaRythm=int8(app.thetaRythmCheckBox.Value);
            
            %% Personal frequency bands
            n=1;
            
                if (ClassicalFrequencyBands==1)         
                    if (muRythm==1)
                        myMuTemporalFilteredData=evalin('base','myMuTemporalFilteredData');  
                        %[artifact] = myArtifactRejection(myMuTemporalFilteredData);
                    end
                    if (alphaRythm==1)
                        myAlphaTemporalFilteredData=evalin('base','myAlphaTemporalFilteredData');           
            
                    end
                    if (betaRythm==1)
                        myBetaTemporalFilteredData=evalin('base','myBetaTemporalFilteredData');           
            
                    end
                    if (deltaRythm==1)
                        myDeltaTemporalFilteredData=evalin('base','myDeltaTemporalFilteredData');           
                    end
                    if (thetaRythm==1)
                        myThetaTemporalFilteredData=evalin('base','myThetaTemporalFilteredData');
            
                    end
                else
                        myFreqBandsTemporalFilteredData=evalin('base','myFreqBandsTemporalFilteredData');
                        while n<length(myFreqBandsTemporalFilteredData.data)+1
                        n=n+1;
                        end
                    %MyFreqBandsTemporalFilteredData.data(1).trial{1} : line of code to get the channel x time matrix;
                end     
        end

        % Callback function
        function ValueEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ApplyspatialfilteringButton
        function spatial_filtering(app, event)
            close all;
            
            Type=app.FilteringMethodDropDown.Value;
            
            %% For the classical frequency bands
            ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
            muRythm=int8(app.muRythmCheckBox.Value);
            alphaRythm=int8(app.muRythmCheckBox.Value);
            betaRythm=int8(app.betaRythmCheckBox.Value);
            deltaRythm=int8(app.deltaRythmCheckBox.Value);
            thetaRythm=int8(app.thetaRythmCheckBox.Value);
            
            %% Personal frequency bands
            myFreqBandsSpatialFilteredData=[];
            myFreqBandsSpectralData=[];
            n=1;
                if (ClassicalFrequencyBands==1)         
                    if (muRythm==1)
                        myMuTemporalFilteredData=evalin('base','myMuTemporalFilteredData');           
                        myMuSpatialFilteredData=SpatialFiltering(myMuTemporalFilteredData,Type,'mu band [7.5 Hz - 12.5 Hz]');
                        myMuSpectralData=FreqTransform(myMuSpatialFilteredData,'mu band [7.5 Hz - 12.5 Hz]');
                        assignin('base', 'myMuSpatialFilteredData', myMuSpatialFilteredData);
                    end
                    if (alphaRythm==1)
                        myAlphaTemporalFilteredData=evalin('base','myAlphaTemporalFilteredData');           
                        myAlphaSpatialFilteredData=SpatialFiltering(myAlphaTemporalFilteredData,Type,'alpha band [8 Hz - 15 Hz]');
                        myAlphaSpectralData=FreqTransform(myAlphaSpatialFilteredData,'alpha band [8 Hz - 15 Hz]');
                        assignin('base', 'myAlphaSpatialFilteredData', myAlphaSpatialFilteredData);
                    end
                    if (betaRythm==1)
                        myBetaTemporalFilteredData=evalin('base','myBetaTemporalFilteredData');           
                        myBetaSpatialFilteredData=SpatialFiltering(myBetaTemporalFilteredData,Type,'beta band  [16 Hz - 31 Hz]');
                        myBetaSpectralData=FreqTransform(myBetaSpatialFilteredData,'beta band  [16 Hz - 31 Hz]');
                        assignin('base', 'myBetaSpatialFilteredData', myBetaSpatialFilteredData);
                    end
                    if (deltaRythm==1)
                        myDeltaTemporalFilteredData=evalin('base','myDeltaTemporalFilteredData');           
                        myDeltaTemporalFilteredData=SpatialFiltering(myDeltaTemporalFilteredData,Type,'delta band [0.1 Hz- 3 Hz]');
                        myDeltaSpectralData=FreqTransform(myDeltaSpatialFilteredData,'delta band [0.1 Hz- 3 Hz]');
                        assignin('base', 'myDeltaSpatialFilteredData', myDeltaSpatialFilteredData);
                    end
                    if (thetaRythm==1)
                        myThetaTemporalFilteredData=evalin('base','myThetaTemporalFilteredData');
                        myThetaSpatialFilteredData=SpatialFiltering(myThetaTemporalFilteredData,Type,'theta band [4 Hz- 7 Hz]');
                        myThetaSpectralData=FreqTransform(myThetaSpatialFilteredData,'theta band [4 Hz- 7 Hz]');
                        assignin('base', 'myThetaSpatialFilteredData', myThetaSpatialFilteredData);
                    end
                else
                        myFreqBandsTemporalFilteredData=evalin('base','myFreqBandsTemporalFilteredData');
                    while n<length(myFreqBandsTemporalFilteredData.data)+1
                        myFreqBandsSpatialFilteredData.data(n)=SpatialFiltering(myFreqBandsTemporalFilteredData.data(n),Type,['band' num2str(n)]);
                        myFreqBandsSpectralData.data(n)=FreqTransform(myFreqBandsSpatialFilteredData.data(n),['band' num2str(n)]);
                        n=n+1;
                    end
                    assignin('base', 'myFreqBandsSpatialFilteredData', myFreqBandsSpatialFilteredData);
            
                        %MyFreqBandsTemporalFilteredData.data(1).trial{1} : line of code to get the channel x time matrix;
                end     

        end

        % Button pushed function: ApplytemporalfilteringButton
        function temporal_filtering(app, event)
            close all;
            %% Get the values from the app
            
            %% Not with the different bands
            BPFilter=int8(app.BPFilterCheckBox.Value);
            NotchFilter=int8(app.NotchFilterCheckBox.Value);
            Type=app.TypeDropDown.Value;
            winType=app.WindowTypeDropDown.Value;
            N=app.OrderEditField.Value;


            %% Get the FT structure 
            myFtData=evalin('base','myFtData');   
            
            %% With the classical bands
            muRythm=int8(app.muRythmCheckBox.Value);
            alphaRythm=int8(app.muRythmCheckBox.Value);
            betaRythm=int8(app.betaRythmCheckBox.Value);
            deltaRythm=int8(app.deltaRythmCheckBox.Value);
            thetaRythm=int8(app.thetaRythmCheckBox.Value);
            
            %% With the perso bands
            LFreqPerso=app.LFreqEditField_perso.Value;
            HFreqPerso=app.HFreqEditField_perso.Value;
            NbOfBands=app.NbofbandsEditField.Value;
            step=(HFreqPerso-LFreqPerso)/NbOfBands;
            fstart=LFreqPerso;
            fend=LFreqPerso+step;
            n=1;
            
            myFreqBandsTemporalFilteredData=[]; % Create the structure that will contain the structure for each frequency band
            myFreqBandsSpectralData=[];
            ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
            
            if (ClassicalFrequencyBands==1)         
                if (muRythm==1)
                    myMuTemporalFilteredData=temporalFtFiltering(myFtData,7.5,12.5,NotchFilter,Type,winType,N,'mu band [7.5 Hz - 12.5 Hz]')
                    myMuSpectralData=FreqTransform(myMuTemporalFilteredData,'mu band [7.5 Hz - 12.5 Hz]');
                    assignin('base', 'myMuTemporalFilteredData', myMuTemporalFilteredData)
                end
                if (alphaRythm==1)
                    myAlphaTemporalFilteredData=temporalFtFiltering(myFtData,8,15,NotchFilter,Type,winType,N,'alpha band [8 Hz - 15 Hz]')
                    myAlphaSpectralData=FreqTransform(myAlphaTemporalFilteredData,'alpha band [8 Hz - 15 Hz]');
                    assignin('base', 'myAlphaTemporalFilteredData', myAlphaTemporalFilteredData)
                end
                if (betaRythm==1)
                    myBetaTemporalFilteredData=temporalFtFiltering(myFtData,16,31,NotchFilter,Type,winType,N,'beta band  [16 Hz - 31 Hz]')
                    myBetaSpectralData=FreqTransform(myBetaTemporalFilteredData,'beta band  [16 Hz - 31 Hz]');
                    assignin('base', 'myBetaTemporalFilteredData', myBetaTemporalFilteredData)
                end
                if (deltaRythm==1)
                    myDeltaTemporalFilteredData=temporalFtFiltering(myFtData,0.1,3,NotchFilter,Type,winType,N,'delta band [0.1 Hz- 3 Hz]')
                    myDeltaSpectralData=FreqTransform(myDeltaTemporalFilteredData,'delta band [0.1 Hz- 3 Hz]');
                    assignin('base', 'myDeltaTemporalFilteredData', myDeltaTemporalFilteredData)
                end
                if (thetaRythm==1)
                    myThetaTemporalFilteredData=temporalFtFiltering(myFtData,4,7,NotchFilter,Type,winType,N,'theta band [4 Hz- 7 Hz]')
                    myThetaSpectralData=FreqTransform(myThetaTemporalFilteredData,'theta band [4 Hz- 7 Hz]');
                    assignin('base', 'myThetaTemporalFilteredData', myThetaTemporalFilteredData)
                end
            else
                while n<NbOfBands+1
                    myFreqBandsTemporalFilteredData.data(n)=temporalFtFiltering(myFtData,fstart,fend,NotchFilter,Type,winType,N,['band ' num2str(n) ' [' num2str(fstart) 'Hz - ' num2str(fend) 'Hz ]' ])
                    myFreqBandsSpectralData.data(n)=FreqTransform(myFreqBandsTemporalFilteredData.data(n),['band ' num2str(n) ' [' num2str(fstart) 'Hz - ' num2str(fend) 'Hz ]' ]);
                    fstart=fstart+step;
                    fend=fend+step;
                    n=n+1;
                end
                    assignin('base', 'myFreqBandsTemporalFilteredData', myFreqBandsTemporalFilteredData);
            end     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SignaloptionsandartefactsPanel = uipanel(app.DataTab);
            app.SignaloptionsandartefactsPanel.Title = 'Signal options and artefacts';
            app.HzLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.sLabel.Text = 's';
            app.dBLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.dBLabel.Text = 'dB';
            app.DurationEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.DurationEditField.Value = 20;
            app.SNREditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.SNREditField.Value = 10;
            app.EyeblinkCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EyeblinkCheckBox.Text = 'Eye blink';
            app.EyeblinkCheckBox.Value = true;
            app.EMGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EMGeffectsCheckBox.Text = 'EMG effects';
            app.EMGeffectsCheckBox.Value = true;
            app.EOGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EOGeffectsCheckBox.Text = 'EOG effects';
            app.EOGeffectsCheckBox.Value = true;
            app.FsEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.FsEditField.Value = 256;
            app.MovementStartingTimeLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.MovementStartingTimeLabel.Text = {'Movement '; 'Starting '; 'Time'};
            app.MovementStartingTimeEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.MovementStartingTimeEditField.Value = 5;
            app.GeneratedataButton = uibutton(app.SignaloptionsandartefactsPanel, 'push');
            app.GeneratedataButton.ButtonPushedFcn = createCallbackFcn(app, @Generate, true);
            app.GeneratedataButton.Text = 'Generate data';
            app.PowerlinesCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.PowerlinesCheckBox.Text = 'Power lines';
            app.PowerlinesCheckBox.Value = true;
            app.MovementDropDownLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.SignaloptionsandartefactsPanel);
            app.MovementDropDown.Items = {'Right hand', 'Left hand', 'Feet'};
            app.MovementDropDown.Value = 'Right hand';
            app.FilePanel = uipanel(app.DataTab);
            app.FilePanel.Title = 'File';
            app.FilenameEditFieldLabel = uilabel(app.FilePanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FilePanel, 'text');
            app.LoaddataButton = uibutton(app.FilePanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoaddataButton.Text = 'Load data';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.SpatialFilteringPanel = uipanel(app.PreprocessingTab);
            app.SpatialFilteringPanel.Title = 'Spatial Filtering';
            app.ApplyspatialfilteringButton = uibutton(app.SpatialFilteringPanel, 'push');
            app.ApplyspatialfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @spatial_filtering, true);
            app.ApplyspatialfilteringButton.Text = 'Apply spatial filtering';
            app.FilteringMethodDropDownLabel = uilabel(app.SpatialFilteringPanel);
            app.FilteringMethodDropDownLabel.Text = 'Filtering Method';
            app.FilteringMethodDropDown = uidropdown(app.SpatialFilteringPanel);
            app.FilteringMethodDropDown.Items = {'CAR', 'ICA', 'CSP', 'LAP'};
            app.FilteringMethodDropDown.Value = 'CAR';
            app.TemporalFilteringPanel = uipanel(app.PreprocessingTab);
            app.TemporalFilteringPanel.Title = 'Temporal Filtering';
            app.BandPassFilterParametersPanel = uipanel(app.TemporalFilteringPanel);
            app.BandPassFilterParametersPanel.Title = 'Band Pass Filter Parameters';
            app.TypeDropDownLabel = uilabel(app.BandPassFilterParametersPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.BandPassFilterParametersPanel);
            app.TypeDropDown.Items = {'fir', 'but', 'firws'};
            app.TypeDropDown.Value = 'fir';
            app.OrderEditFieldLabel = uilabel(app.BandPassFilterParametersPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.BandPassFilterParametersPanel, 'numeric');
            app.OrderEditField.Value = 4;
            app.WindowTypeLabel = uilabel(app.BandPassFilterParametersPanel);
            app.WindowTypeLabel.Text = {'Window '; 'Type'};
            app.WindowTypeDropDown = uidropdown(app.BandPassFilterParametersPanel);
            app.WindowTypeDropDown.Items = {'', 'hann', 'hamming', 'blackman', 'kaiser'};
            app.WindowTypeDropDown.Value = 'hamming';
            app.NotchFilterPanel = uipanel(app.TemporalFilteringPanel);
            app.NotchFilterPanel.Title = 'Notch Filter ';
            app.FreqEditFieldLabel = uilabel(app.NotchFilterPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.NotchFilterPanel, 'numeric');
            app.FreqEditField.Value = 50;
            app.HzLabel_6 = uilabel(app.NotchFilterPanel);
            app.HzLabel_6.Text = 'Hz';
            app.ApplytemporalfilteringButton = uibutton(app.TemporalFilteringPanel, 'push');
            app.ApplytemporalfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @temporal_filtering, true);
            app.ApplytemporalfilteringButton.Text = 'Apply temporal filtering';
            app.FiltersPanel = uipanel(app.TemporalFilteringPanel);
            app.FiltersPanel.Title = 'Filters';
            app.NotchFilterCheckBox = uicheckbox(app.FiltersPanel);
            app.NotchFilterCheckBox.Text = 'Notch Filter';
            app.NotchFilterCheckBox.Value = true;
            app.BPFilterCheckBox = uicheckbox(app.FiltersPanel);
            app.BPFilterCheckBox.Text = 'BP Filter';
            app.BPFilterCheckBox.Value = true;
            app.FrequencybandsButtonGroup = uibuttongroup(app.TemporalFilteringPanel);
            app.FrequencybandsButtonGroup.Title = 'Frequency bands';
            app.ClassicalfrequencybandsButton = uiradiobutton(app.FrequencybandsButtonGroup);
            app.ClassicalfrequencybandsButton.Text = 'Classical frequency bands';
            app.ClassicalfrequencybandsButton.Value = true;
            app.PersonnalisedfrequencybandsButton = uiradiobutton(app.FrequencybandsButtonGroup);
            app.PersonnalisedfrequencybandsButton.Text = 'Personnalised frequency bands';
            app.ClassicalfrequencybandsPanel = uipanel(app.TemporalFilteringPanel);
            app.ClassicalfrequencybandsPanel.Title = 'Classical frequency bands';
            app.muRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.muRythmCheckBox.Text = 'μ Rythm [7.5 Hz - 12.5 Hz]';
            app.muRythmCheckBox.Value = true;
            app.deltaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.deltaRythmCheckBox.Text = 'δ Rythm [0.1 Hz - 3 Hz]';
            app.thetaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.thetaRythmCheckBox.Text = 'θ Rythm [4 Hz - 7 Hz]';
            app.alphaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.alphaRythmCheckBox.Text = 'α Rythm [8 Hz - 15 Hz]';
            app.alphaRythmCheckBox.Value = true;
            app.betaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.betaRythmCheckBox.Text = ' β Rythm [16 Hz - 31 Hz]';
            app.PersonnalisedfrequencybandsPanel = uipanel(app.TemporalFilteringPanel);
            app.PersonnalisedfrequencybandsPanel.Title = 'Personnalised frequency bands';
            app.NbofbandsEditFieldLabel = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.NbofbandsEditFieldLabel.Text = 'Nb of bands';
            app.NbofbandsEditField = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.NbofbandsEditField.Value = 1;
            app.LFreqEditField_2Label = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.LFreqEditField_2Label.Text = 'LFreq';
            app.LFreqEditField_perso = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.LFreqEditField_perso.Value = 8;
            app.HFreqEditField_2Label = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.HFreqEditField_2Label.Text = 'HFreq';
            app.HFreqEditField_perso = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.HFreqEditField_perso.Value = 15;
            app.ArtifactRejectionPanel = uipanel(app.PreprocessingTab);
            app.ArtifactRejectionPanel.Title = 'Artifact Rejection';
            app.ApplyrejectionButton = uibutton(app.ArtifactRejectionPanel, 'push');
            app.ApplyrejectionButton.ButtonPushedFcn = createCallbackFcn(app, @RejectArtifact, true);
            app.ApplyrejectionButton.Text = 'Apply rejection';
            app.ExtractionTab = uitab(app.TabGroup);
            app.ExtractionTab.Title = 'Extraction';
            app.ExtractionMethodButtonGroup = uibuttongroup(app.ExtractionTab);
            app.ExtractionMethodButtonGroup.Title = 'Extraction Method';
            app.PowerbandsButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.PowerbandsButton.Text = 'Power bands';
            app.PowerbandsButton.Value = true;
            app.WaveletsButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.WaveletsButton.Text = 'Wavelets';
            app.ARModelButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.ARModelButton.Text = 'AR Model';
            app.PeakDetectionButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.PeakDetectionButton.Text = 'Peak Detection';
            app.VarianceButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.VarianceButton.Text = 'Variance';
            app.TemplateMatchingButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.TemplateMatchingButton.Text = 'TemplateMatching';
            app.TimeIntegrationButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.TimeIntegrationButton.Text = 'Time Integration';
            app.BuildFeatureVectorButton = uibutton(app.ExtractionTab, 'push');
            app.BuildFeatureVectorButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtraction, true);
            app.BuildFeatureVectorButton.Text = 'Build Feature Vector';
            app.ClassificiationTab = uitab(app.TabGroup);
            app.ClassificiationTab.Title = 'Classificiation';
            app.ExtractionMethodButtonGroup_2 = uibuttongroup(app.ClassificiationTab);
            app.ExtractionMethodButtonGroup_2.Title = 'Extraction Method';
            app.LDAButton = uiradiobutton(app.ExtractionMethodButtonGroup_2);
            app.LDAButton.Text = 'LDA';
            app.LDAButton.Value = true;
            app.SVMButton = uiradiobutton(app.ExtractionMethodButtonGroup_2);
            app.SVMButton.Text = 'SVM';
            app.ARModelButton_2 = uiradiobutton(app.ExtractionMethodButtonGroup_2);
            app.ARModelButton_2.Text = 'AR Model';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEG_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end